classdef MotionProcessor < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MotionUI                     matlab.ui.Figure
        OuterGrid                    matlab.ui.container.GridLayout
        MarkerDataButton             matlab.ui.control.Button
        GRFDataButton                matlab.ui.control.Button
        EMGDataButton                matlab.ui.control.Button
        CalorimetryDataButton        matlab.ui.control.Button
        MarkerDataPath               matlab.ui.control.EditField
        GRFDataPath                  matlab.ui.control.EditField
        EMGDataPath                  matlab.ui.control.EditField
        CalorimetryDataPath          matlab.ui.control.EditField
        SubsetButton                 matlab.ui.control.Button
        SubsetSelectedLabel          matlab.ui.control.Label
        SubsetIndicator              matlab.ui.control.Lamp
        CoordinateRotationPanel      matlab.ui.container.Panel
        CoordinateRotationGrid       matlab.ui.container.GridLayout
        GRFLabel                     matlab.ui.control.Label
        ForwardLabel                 matlab.ui.control.Label
        UpLabel                      matlab.ui.control.Label
        RightLabel                   matlab.ui.control.Label
        GRFForwardDropDown           matlab.ui.control.DropDown
        GRFUpDropDown                matlab.ui.control.DropDown
        GRFRightDropDown             matlab.ui.control.DropDown
        MarkersLabel                 matlab.ui.control.Label
        MarkersForwardDropDown       matlab.ui.control.DropDown
        MarkersUpDropDown            matlab.ui.control.DropDown
        MarkersRightDropDown         matlab.ui.control.DropDown
        GaitSegmentationPanel        matlab.ui.container.Panel
        GaitSegmentationGrid         matlab.ui.container.GridLayout
        ModePanel                    matlab.ui.container.ButtonGroup
        StanceButton                 matlab.ui.control.RadioButton
        KinematicsButton             matlab.ui.control.RadioButton
        FeetPanel                    matlab.ui.container.ButtonGroup
        LeftButton                   matlab.ui.control.RadioButton
        RightButton                  matlab.ui.control.RadioButton
        BothButton                   matlab.ui.control.RadioButton
        DataProcessingOptionsPanel   matlab.ui.container.Panel
        DataProcessingOptionsGrid    matlab.ui.container.GridLayout
        CoordinateRotationLabel      matlab.ui.control.Label
        CoordinateRotationSwitch     matlab.ui.control.Switch
        MotionSynchronisationLabel   matlab.ui.control.Label
        MotionSynchronisationSwitch  matlab.ui.control.Switch
        MotionCompensationLabel      matlab.ui.control.Label
        MotionCompensationSwitch     matlab.ui.control.Switch
        CoordinateTranslationLabel   matlab.ui.control.Label
        CoordinateTranslationSwitch  matlab.ui.control.Switch
        GaitSegmentationLabel        matlab.ui.control.Label
        GaitSegmentationSwitch       matlab.ui.control.Switch
        CoordinateTranslationPanel   matlab.ui.container.Panel
        CoordinateTranslationGrid    matlab.ui.container.GridLayout
        XEditFieldLabel              matlab.ui.control.Label
        XOffset                      matlab.ui.control.NumericEditField
        YEditFieldLabel              matlab.ui.control.Label
        YOffset                      matlab.ui.control.NumericEditField
        ZEditFieldLabel              matlab.ui.control.Label
        ZOffset                      matlab.ui.control.NumericEditField
        LoadCalibrationDataButton    matlab.ui.control.Button
        ManualInputButton            matlab.ui.control.StateButton
        OffsetsmLabel                matlab.ui.control.Label
        Lamp_2                       matlab.ui.control.Lamp
        MotionCompensationPanel      matlab.ui.container.Panel
        MotionCompensationGrid       matlab.ui.container.GridLayout
        SpeedmsEditFieldLabel        matlab.ui.control.Label
        SpeedInput                   matlab.ui.control.NumericEditField
        TiltdegEditFieldLabel        matlab.ui.control.Label
        TiltInput                    matlab.ui.control.NumericEditField
        SpeedDataButton              matlab.ui.control.StateButton
        SpeedDataPath                matlab.ui.control.EditField
        ManualSpeedInputButton       matlab.ui.control.StateButton
        Lamp                         matlab.ui.control.Lamp
        MotionSynchronisationPanel   matlab.ui.container.Panel
        MotionSynchronisationGrid    matlab.ui.container.GridLayout
        BaselineLabel                matlab.ui.control.Label
        TimeDelaysLabel              matlab.ui.control.Label
        MarkersCheckBox              matlab.ui.control.CheckBox
        GRFCheckBox                  matlab.ui.control.CheckBox
        EMGCheckBox                  matlab.ui.control.CheckBox
        CalorimetryCheckBox          matlab.ui.control.CheckBox
        MarkersDelay                 matlab.ui.control.NumericEditField
        GRFDelay                     matlab.ui.control.NumericEditField
        EMGDelay                     matlab.ui.control.NumericEditField
        CalorimetryDelay             matlab.ui.control.NumericEditField
        FooterToolbox                matlab.ui.control.Label
        FooterAuthor                 matlab.ui.control.Label
        FeedbackPanel                matlab.ui.control.TextArea
        ProcessButton                matlab.ui.control.Button
        DisplayErrorInfoSwitchLabel  matlab.ui.control.Label
        ErrorSwitch                  matlab.ui.control.Switch
        SaveDirectoryButton          matlab.ui.control.Button
        SaveDirectoryPath            matlab.ui.control.EditField
        MarkerDataClearButton        matlab.ui.control.Button
        GRFDataClearButton           matlab.ui.control.Button
        EMGDataClearButton           matlab.ui.control.Button
        CalorimetryDataClearButton   matlab.ui.control.Button
        SaveDirectoryClearButton     matlab.ui.control.Button
    end

    properties (Access = private)
       
        FileSelector % Separate helper app for selecting subset of files
        FileSet
        FileSubset
        NInputs = 0
        
    end
    
    methods (Access = private)
        
        function value = getFirstDataPath(app)
            
            options = {'MarkerDataPath', 'GRFDataPath', 'EMGDataPath', ...
                'CalorimetryDataPath'};
            
            value = '';
            for i = 1:length(options)
                if ~isempty(app.(options{i}).Value)
                    value = app.(options{i}).Value;
                    break;
                end
            end
            
        end
        
        function updateFeedbackPanel(app, str)
            
            if isempty(app.FeedbackPanel.Value{1})
                app.FeedbackPanel.Value{1} = str;
            elseif length(app.FeedbackPanel.Value) == 1
                app.FeedbackPanel.Value{2} = str;
            elseif length(app.FeedbackPanel.Value) == 2
                app.FeedbackPanel.Value{3} = str;
            else
                app.FeedbackPanel.Value{1} = app.FeedbackPanel.Value{2};
                app.FeedbackPanel.Value{2} = app.FeedbackPanel.Value{3};
                app.FeedbackPanel.Value{3} = str;
            end
            
        end
        
        function adjustMarkerRotationOptions(app, value)
            app.MarkersForwardDropDown.Enable = value;
            app.MarkersUpDropDown.Enable = value;
            app.MarkersRightDropDown.Enable = value;
        end
        
        function adjustGRFRotationOptions(app, value)
            app.GRFForwardDropDown.Enable = value;
            app.GRFUpDropDown.Enable = value;
            app.GRFRightDropDown.Enable = value;
        end
        
        function adjustMotionCompensationOptions(app, value)
            
            app.SpeedDataButton.Enable = value;
            app.ManualSpeedInputButton.Enable = value;
            app.SpeedInput.Enable = value;
            app.TiltInput.Enable = value;
            
        end
        
        function adjustMotionSynchronisationOptions(app, value, array)
            
            if array(1)
                app.MarkersCheckBox.Enable = value;
                app.MarkersDelay.Enable = value;
            end
            if array(2)
                app.GRFCheckBox.Enable = value;
                app.GRFDelay.Enable = value;
            end
            if array(3)
                app.EMGCheckBox.Enable = value;
                app.EMGDelay.Enable = value;
            end
            if array(4)
                app.CalorimetryCheckBox.Enable = value;
                app.CalorimetryDelay.Enable = value;
            end
            
        end
        
        function adjustGaitSegmentationOptions(app, value)
            
            app.StanceButton.Enable = value;
            app.KinematicsButton.Enable = value;
            app.LeftButton.Enable = value;
            app.RightButton.Enable = value;
            app.BothButton.Enable = value;
            
        end
        
        function adjustManualTranslationOptions(app, value)
            app.XOffset.Enable = value;
            app.YOffset.Enable = value;
            app.ZOffset.Enable = value;
        end
        
        function adjustCoordinateCalibrationOptions(app, value)
            app.LoadCalibrationDataButton.Enable = value;
            app.ManualInputButton.Enable = value;
            
            switch value
                case 'Off'
                    manual_value = 'Off';
                case 'On'
                    manual_value = app.ManualInputButton.Value;
            end
            
            app.adjustManualTranslationOptions(manual_value);
        end
        
        function states = getDataProcessingOptionStates(app)
            states{1} = app.CoordinateRotationSwitch.Value;
            states{2} = app.CoordinateTranslationSwitch.Value;
            states{3} = app.MotionSynchronisationSwitch.Value;
            states{4} = app.MotionCompensationSwitch.Value;
            states{5} = app.GaitSegmentationSwitch.Value;
            
        end
        
        function changeDataProcessingOptions(app, states, value)
            
            % Simulate turning switches off and on again
            if strcmp(states{1}, 'On')
                app.CoordinateRotationSwitch.Value = value;
                app.coordinateRotationSwitchPressed();
            end
            if strcmp(states{2}, 'On')
                app.CoordinateTranslationSwitch.Value = value;
                app.coordinateTranslationSwitchPressed();
            end
            if strcmp(states{3}, 'On')
                app.MotionSynchronisationSwitch.Value = value;
                app.motionSynchronisationSwitchPressed();
            end
            if strcmp(states{4}, 'On')
                app.MotionCompensationSwitch.Value = value;
                app.motionCompensationSwitchPressed();
            end
            if strcmp(states{5}, 'On')
                app.GaitSegmentationSwitch.Value = value;
                app.gaitSegmentationSwitchPressed();
            end
            
        end
        
        function resetDataProcessingOptions(app)
            
            states = app.getDataProcessingOptionStates();
            app.changeDataProcessingOptions(states, 'Off');
            app.changeDataProcessingOptions(states, 'On');
            
        end
        
        function adjustBaselineChecks(app, values)
            
            app.MarkersCheckBox.Value = values(1);
            app.GRFCheckBox.Value = values(2);
            app.EMGCheckBox.Value = values(3);
            app.CalorimetryCheckBox.Value = values(4);
            
        end
    
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: CoordinateRotationSwitch
        function coordinateRotationSwitchPressed(app, event)
            value = app.CoordinateRotationSwitch.Value;
            
            if strcmp(value, 'Off')
                app.adjustMarkerRotationOptions(value);
                app.adjustGRFRotationOptions(value);
            elseif strcmp(value, 'On') 
                if ~isempty(app.MarkerDataPath.Value)
                    app.adjustMarkerRotationOptions(value);
                end
                if ~isempty(app.GRFDataPath.Value)
                    app.adjustGRFRotationOptions(value);
                end
                if isempty(app.MarkerDataPath.Value) && ...
                        isempty(app.GRFDataPath.Value)
                    app.CoordinateRotationSwitch.Value = 'Off';
                    str = 'Require marker or GRF data for coordinate rotation.';
                    app.updateFeedbackPanel(str);
                end
            end
            
        end

        % Value changed function: CoordinateTranslationSwitch
        function coordinateTranslationSwitchPressed(app, event)
            value = app.CoordinateTranslationSwitch.Value;
            
            if ~isempty(app.MarkerDataPath.Value)
                app.adjustCoordinateCalibrationOptions(value);
            else
                app.adjustCoordinateCalibrationOptions('Off');
                app.CoordinateTranslationSwitch.Value = 'Off';
                str = 'Require marker data for coordinate translation.';
                app.updateFeedbackPanel(str);
            end
            
        end

        % Button pushed function: MarkerDataButton
        function getMarkerDataPath(app, event)
            % Use try-catch so user can cancel input without error
            try 
                % Get and set marker data path
                app.MarkerDataPath.Value = ...
                    uigetdir(pwd, 'Select folder containing marker data.');
                
                % Enable clear option
                app.MarkerDataClearButton.Enable = 'On';
                
                % Reset data processing settings
                app.resetDataProcessingOptions();
                
                % Add to the input count.
                app.NInputs = app.NInputs + 1;
            catch
            end
        end

        % Button pushed function: MarkerDataClearButton
        function clearMarkerDataPath(app, event)
            % Clear marker data path
            app.MarkerDataPath.Value = '';
            app.MarkerDataClearButton.Enable = 'Off';
            
            % Subtract to the input count.
            app.NInputs = app.NInputs - 1;
            
            % Reset data processing settings
            app.resetDataProcessingOptions();
            
        end

        % Button pushed function: GRFDataButton
        function getGRFDataPath(app, event)
            % Use try-catch so user can cancel input without error
            try 
                % Get and set data path
                app.GRFDataPath.Value = ...
                    uigetdir(pwd, 'Select folder containing GRF data.');
                
                % Enable clear option
                app.GRFDataClearButton.Enable = 'On';
                
                % Reset data processing settings
                app.resetDataProcessingOptions();
                
                % Add to the input count.
                app.NInputs = app.NInputs + 1;
            catch
            end
        end

        % Button pushed function: EMGDataButton
        function getEMGDataPath(app, event)
            % Use try-catch so user can cancel input without error
            try 
                % Get and set data path
                app.EMGDataPath.Value = ...
                    uigetdir(pwd, 'Select folder containing EMG data.');
                
                % Enable clear option
                app.EMGDataClearButton.Enable = 'On';
                
                % Reset data processing settings
                app.resetDataProcessingOptions();
                
                % Add to the input count.
                app.NInputs = app.NInputs + 1;
            catch
            end
        end

        % Button pushed function: CalorimetryDataButton
        function getCalorimetryDataPath(app, event)
            % Use try-catch so user can cancel input without error
            try 
                % Get and set data path
                app.CalorimetryDataPath.Value = ...
                    uigetdir(pwd, 'Select folder containing calorimetry data.');
                
                % Enable clear option
                app.CalorimetryDataClearButton.Enable = 'On';
                
                % Reset data processing settings
                app.resetDataProcessingOptions();
                
                % Add to the input count.
                app.NInputs = app.NInputs + 1;
            catch
            end
        end

        % Button pushed function: SaveDirectoryButton
        function getSaveDirectoryPath(app, event)
            % Use try-catch so user can cancel input without error
            try 
                % Get and set save directory
                app.SaveDirectoryPath.Value = ...
                    uigetdir(pwd, 'Select or create results folder.');
                
                % Enable clear option
                app.SaveDirectoryClearButton.Enable = 'On';
            catch
            end
        end

        % Value changed function: MotionSynchronisationSwitch
        function motionSynchronisationSwitchPressed(app, event)
            value = app.MotionSynchronisationSwitch.Value;
            
            if strcmp(value, 'Off')
                app.adjustMotionSynchronisationOptions(value, [1 1 1 1]);
            elseif strcmp(value, 'On')
                if app.NInputs() < 2
                    app.MotionSynchronisationSwitch.Value = 'Off';
                    str = ...
                        'Require 2 or more inputs for motion synchronisation.';
                    app.updateFeedbackPanel(str);
                else
                    array = [false false false false];
                    if ~isempty(app.MarkerDataPath.Value)
                        array(1) = true;
                    end
                    if ~isempty(app.GRFDataPath.Value)
                        array(2) = true;
                    end
                    if ~isempty(app.EMGDataPath.Value)
                        array(3) = true;
                    end
                    if ~isempty(app.CalorimetryDataPath.Value)
                        array(4) = true;
                    end
                    app.adjustMotionSynchronisationOptions(value, array);
                end
            end
            
        end

        % Value changed function: MotionCompensationSwitch
        function motionCompensationSwitchPressed(app, event)
            value = app.MotionCompensationSwitch.Value;
            
            if strcmp(value, 'Off')
                app.adjustMotionCompensationOptions(value);
            elseif strcmp(value, 'On')
                if isempty(app.MarkerDataPath.Value) && ...
                        isempty(app.GRFDataPath.Value)
                    app.MotionSynchronisationSwitch.Value = 'Off';
                    str = 'Require marker or GRF data for motion compensation.';
                    app.updateFeedbackPanel(str);
                end
            end
            
        end

        % Value changed function: GaitSegmentationSwitch
        function gaitSegmentationSwitchPressed(app, event)
            value = app.GaitSegmentationSwitch.Value;
            
            if strcmp(value, 'Off')
                app.adjustGaitSegmentationOptions(value);
            elseif strcmp(value, 'On')
                if isempty(app.MarkerDataPath.Value) && ...
                        isempty(app.GRFDataPath.Value)
                    app.GaitSegmentationSwitch.Value = 'Off';
                    str = 'Require marker or GRF data for gait segmentation.';
                    app.updateFeedbackPanel(str);
                end 
            end
        end

        % Button pushed function: GRFDataClearButton
        function clearGRFDataPath(app, event)
            % Clear data path
            app.GRFDataPath.Value = '';
            app.GRFDataClearButton.Enable = 'Off';
            
            % Subtract to the input count.
            app.NInputs = app.NInputs - 1;
            
            % Reset data processing settings
            app.resetDataProcessingOptions();
            
        end

        % Button pushed function: EMGDataClearButton
        function clearEMGDataPath(app, event)
            % Clear data path
            app.EMGDataPath.Value = '';
            app.EMGDataClearButton.Enable = 'Off';
            
            % Subtract to the input count.
            app.NInputs = app.NInputs - 1;
            
            % Reset data processing settings
            app.resetDataProcessingOptions();
            
        end

        % Button pushed function: CalorimetryDataClearButton
        function clearCalorimetryDataPath(app, event)
            % Clear data path
            app.CalorimetryDataPath.Value = '';
            app.CalorimetryDataClearButton.Enable = 'Off';
            
            % Subtract to the input count.
            app.NInputs = app.NInputs - 1;
            
            % Reset data processing settings
            app.resetDataProcessingOptions();
            
        end

        % Button pushed function: SaveDirectoryClearButton
        function clearSaveDirectoryPath(app, event)
            % Clear data path
            app.SaveDirectoryPath.Value = '';
            app.SaveDirectoryClearButton.Enable = 'Off';
        end

        % Value changed function: MarkersCheckBox
        function markersChecked(app, event)
            
            app.adjustBaselineChecks([1 0 0 0]);
            app.MarkersDelay.Value = 0;
            
        end

        % Value changed function: GRFCheckBox
        function grfChecked(app, event)
            
            app.adjustBaselineChecks([0 1 0 0]);
            app.GRFDelay.Value = 0;
            
        end

        % Value changed function: EMGCheckBox
        function emgChecked(app, event)
            
            app.adjustBaselineChecks([0 0 1 0]);
            app.EMGDelay.Value = 0;
            
        end

        % Value changed function: CalorimetryCheckBox
        function calorimetryChecked(app, event)
            
            app.adjustBaselineChecks([0 0 0 1]);
            app.CalorimetryDelay.Value = 0;
            
        end

        % Value changed function: MarkersDelay
        function markerDelayModified(app, event)
            
            if app.MarkersCheckBox.Value
                app.MarkersDelay.Value = 0;
                str = 'Baseline must have 0 delay.';
                app.updateFeedbackPanel(str);
            end
            
        end

        % Value changed function: GRFDelay
        function grfDelayModified(app, event)
            
            if app.GRFCheckBox.Value
                app.GRFDelay.Value = 0;
                str = 'Baseline must have 0 delay.';
                app.updateFeedbackPanel(str);
            end
            
        end

        % Value changed function: EMGDelay
        function emgDelayModified(app, event)
            
            if app.EMGCheckBox.Value
                app.EMGDelay.Value = 0;
                str = 'Baseline must have 0 delay.';
                app.updateFeedbackPanel(str);
            end
            
        end

        % Value changed function: CalorimetryDelay
        function calorimetryDelayModified(app, event)
            
            if app.CalorimetryCheckBox.Value
                app.CalorimetryDelay.Value = 0;
                str = 'Baseline must have 0 delay.';
                app.updateFeedbackPanel(str);
            end
            
        end

        % Button pushed function: SubsetButton
        function chooseFileSubset(app, event)
            app.SubsetButton.Enable = 'Off';
            
            path = app.getFirstDataPath();
            
            if isempty(path)
                str = 'Require data to choose file subset.';
                app.updateFeedbackPanel(str);
                app.FileSelectorButton.Enable = 'On';
            else
                [~, files] = dirNoDots(path);
            end
            app.FileSet = files;
            app.FileSelector = FileSelectorApp(app, files);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MotionUI = uifigure('Visible', 'off');
            app.MotionUI.Name = 'Motion Processor';
            app.OuterGrid = uigridlayout(app.MotionUI);
            app.MarkerDataButton = uibutton(app.OuterGrid, 'push');
            app.MarkerDataButton.ButtonPushedFcn = createCallbackFcn(app, @getMarkerDataPath, true);
            app.MarkerDataButton.Text = 'Marker Data';
            app.GRFDataButton = uibutton(app.OuterGrid, 'push');
            app.GRFDataButton.ButtonPushedFcn = createCallbackFcn(app, @getGRFDataPath, true);
            app.GRFDataButton.Text = 'GRF Data';
            app.EMGDataButton = uibutton(app.OuterGrid, 'push');
            app.EMGDataButton.ButtonPushedFcn = createCallbackFcn(app, @getEMGDataPath, true);
            app.EMGDataButton.Text = 'EMG Data';
            app.CalorimetryDataButton = uibutton(app.OuterGrid, 'push');
            app.CalorimetryDataButton.ButtonPushedFcn = createCallbackFcn(app, @getCalorimetryDataPath, true);
            app.CalorimetryDataButton.Text = 'Calorimetry Data';
            app.MarkerDataPath = uieditfield(app.OuterGrid, 'text');
            app.MarkerDataPath.Editable = 'off';
            app.GRFDataPath = uieditfield(app.OuterGrid, 'text');
            app.GRFDataPath.Editable = 'off';
            app.EMGDataPath = uieditfield(app.OuterGrid, 'text');
            app.EMGDataPath.Editable = 'off';
            app.CalorimetryDataPath = uieditfield(app.OuterGrid, 'text');
            app.CalorimetryDataPath.Editable = 'off';
            app.SubsetButton = uibutton(app.OuterGrid, 'push');
            app.SubsetButton.ButtonPushedFcn = createCallbackFcn(app, @chooseFileSubset, true);
            app.SubsetButton.Text = 'Choose Subset of Data Files';
            app.SubsetSelectedLabel = uilabel(app.OuterGrid);
            app.SubsetSelectedLabel.Text = 'Subset Selected?';
            app.SubsetIndicator = uilamp(app.OuterGrid);
            app.CoordinateRotationPanel = uipanel(app.OuterGrid);
            app.CoordinateRotationPanel.Title = 'Coordinate Rotation';
            app.CoordinateRotationGrid = uigridlayout(app.CoordinateRotationPanel);
            app.GRFLabel = uilabel(app.CoordinateRotationGrid);
            app.GRFLabel.Text = 'GRF';
            app.ForwardLabel = uilabel(app.CoordinateRotationGrid);
            app.ForwardLabel.Text = 'Forward';
            app.UpLabel = uilabel(app.CoordinateRotationGrid);
            app.UpLabel.Text = 'Up';
            app.RightLabel = uilabel(app.CoordinateRotationGrid);
            app.RightLabel.Text = 'Right';
            app.GRFForwardDropDown = uidropdown(app.CoordinateRotationGrid);
            app.GRFForwardDropDown.Items = {'+x', '-x', '+y', '-y', '+z', '-z'};
            app.GRFForwardDropDown.Enable = 'off';
            app.GRFForwardDropDown.Value = '+x';
            app.GRFUpDropDown = uidropdown(app.CoordinateRotationGrid);
            app.GRFUpDropDown.Items = {'+x', '-x', '+y', '-y', '+z', '-z'};
            app.GRFUpDropDown.Enable = 'off';
            app.GRFUpDropDown.Value = '+x';
            app.GRFRightDropDown = uidropdown(app.CoordinateRotationGrid);
            app.GRFRightDropDown.Items = {'+x', '-x', '+y', '-y', '+z', '-z'};
            app.GRFRightDropDown.Enable = 'off';
            app.GRFRightDropDown.Value = '+x';
            app.MarkersLabel = uilabel(app.CoordinateRotationGrid);
            app.MarkersLabel.Text = 'Markers';
            app.MarkersForwardDropDown = uidropdown(app.CoordinateRotationGrid);
            app.MarkersForwardDropDown.Items = {'+x', '-x', '+y', '-y', '+z', '-z'};
            app.MarkersForwardDropDown.Enable = 'off';
            app.MarkersForwardDropDown.Value = '+x';
            app.MarkersUpDropDown = uidropdown(app.CoordinateRotationGrid);
            app.MarkersUpDropDown.Items = {'+x', '-x', '+y', '-y', '+z', '-z'};
            app.MarkersUpDropDown.Enable = 'off';
            app.MarkersUpDropDown.Value = '+x';
            app.MarkersRightDropDown = uidropdown(app.CoordinateRotationGrid);
            app.MarkersRightDropDown.Items = {'+x', '-x', '+y', '-y', '+z', '-z'};
            app.MarkersRightDropDown.Enable = 'off';
            app.MarkersRightDropDown.Value = '+x';
            app.GaitSegmentationPanel = uipanel(app.OuterGrid);
            app.GaitSegmentationPanel.Title = 'Gait Segmentation';
            app.GaitSegmentationGrid = uigridlayout(app.GaitSegmentationPanel);
            app.ModePanel = uibuttongroup(app.GaitSegmentationGrid);
            app.ModePanel.Title = 'Mode';
            app.StanceButton = uiradiobutton(app.ModePanel);
            app.StanceButton.Enable = 'off';
            app.StanceButton.Text = 'Stance';
            app.StanceButton.Value = true;
            app.KinematicsButton = uiradiobutton(app.ModePanel);
            app.KinematicsButton.Enable = 'off';
            app.KinematicsButton.Text = 'Kinematics';
            app.FeetPanel = uibuttongroup(app.GaitSegmentationGrid);
            app.FeetPanel.Title = 'Feet';
            app.LeftButton = uiradiobutton(app.FeetPanel);
            app.LeftButton.Enable = 'off';
            app.LeftButton.Text = 'Left';
            app.LeftButton.Value = true;
            app.RightButton = uiradiobutton(app.FeetPanel);
            app.RightButton.Enable = 'off';
            app.RightButton.Text = 'Right';
            app.BothButton = uiradiobutton(app.FeetPanel);
            app.BothButton.Enable = 'off';
            app.BothButton.Text = 'Both';
            app.DataProcessingOptionsPanel = uipanel(app.OuterGrid);
            app.DataProcessingOptionsPanel.Title = 'Data Processing Options';
            app.DataProcessingOptionsGrid = uigridlayout(app.DataProcessingOptionsPanel);
            app.CoordinateRotationLabel = uilabel(app.DataProcessingOptionsGrid);
            app.CoordinateRotationLabel.Text = 'Coordinate Rotation';
            app.CoordinateRotationSwitch = uiswitch(app.DataProcessingOptionsGrid, 'slider');
            app.CoordinateRotationSwitch.ValueChangedFcn = createCallbackFcn(app, @coordinateRotationSwitchPressed, true);
            app.MotionSynchronisationLabel = uilabel(app.DataProcessingOptionsGrid);
            app.MotionSynchronisationLabel.Text = 'Motion Synchronisation';
            app.MotionSynchronisationSwitch = uiswitch(app.DataProcessingOptionsGrid, 'slider');
            app.MotionSynchronisationSwitch.ValueChangedFcn = createCallbackFcn(app, @motionSynchronisationSwitchPressed, true);
            app.MotionCompensationLabel = uilabel(app.DataProcessingOptionsGrid);
            app.MotionCompensationLabel.Text = 'Motion Compensation';
            app.MotionCompensationSwitch = uiswitch(app.DataProcessingOptionsGrid, 'slider');
            app.MotionCompensationSwitch.ValueChangedFcn = createCallbackFcn(app, @motionCompensationSwitchPressed, true);
            app.CoordinateTranslationLabel = uilabel(app.DataProcessingOptionsGrid);
            app.CoordinateTranslationLabel.Text = 'Coordinate Translation';
            app.CoordinateTranslationSwitch = uiswitch(app.DataProcessingOptionsGrid, 'slider');
            app.CoordinateTranslationSwitch.ValueChangedFcn = createCallbackFcn(app, @coordinateTranslationSwitchPressed, true);
            app.GaitSegmentationLabel = uilabel(app.DataProcessingOptionsGrid);
            app.GaitSegmentationLabel.Text = 'Gait Segmentation';
            app.GaitSegmentationSwitch = uiswitch(app.DataProcessingOptionsGrid, 'slider');
            app.GaitSegmentationSwitch.ValueChangedFcn = createCallbackFcn(app, @gaitSegmentationSwitchPressed, true);
            app.CoordinateTranslationPanel = uipanel(app.OuterGrid);
            app.CoordinateTranslationPanel.Title = 'Coordinate Translation';
            app.CoordinateTranslationGrid = uigridlayout(app.CoordinateTranslationPanel);
            app.XEditFieldLabel = uilabel(app.CoordinateTranslationGrid);
            app.XEditFieldLabel.Enable = 'off';
            app.XEditFieldLabel.Text = 'X';
            app.XOffset = uieditfield(app.CoordinateTranslationGrid, 'numeric');
            app.XOffset.ValueDisplayFormat = '%.2f';
            app.XOffset.Editable = 'off';
            app.XOffset.Enable = 'off';
            app.YEditFieldLabel = uilabel(app.CoordinateTranslationGrid);
            app.YEditFieldLabel.Enable = 'off';
            app.YEditFieldLabel.Text = 'Y';
            app.YOffset = uieditfield(app.CoordinateTranslationGrid, 'numeric');
            app.YOffset.ValueDisplayFormat = '%.2f';
            app.YOffset.Editable = 'off';
            app.YOffset.Enable = 'off';
            app.ZEditFieldLabel = uilabel(app.CoordinateTranslationGrid);
            app.ZEditFieldLabel.Enable = 'off';
            app.ZEditFieldLabel.Text = 'Z';
            app.ZOffset = uieditfield(app.CoordinateTranslationGrid, 'numeric');
            app.ZOffset.ValueDisplayFormat = '%.2f';
            app.ZOffset.Editable = 'off';
            app.ZOffset.Enable = 'off';
            app.LoadCalibrationDataButton = uibutton(app.CoordinateTranslationGrid, 'push');
            app.LoadCalibrationDataButton.Enable = 'off';
            app.LoadCalibrationDataButton.Text = 'Load Calibration Data';
            app.ManualInputButton = uibutton(app.CoordinateTranslationGrid, 'state');
            app.ManualInputButton.Enable = 'off';
            app.ManualInputButton.Text = 'Manual Input';
            app.OffsetsmLabel = uilabel(app.CoordinateTranslationGrid);
            app.OffsetsmLabel.Text = 'Offsets (m)';
            app.Lamp_2 = uilamp(app.CoordinateTranslationGrid);
            app.MotionCompensationPanel = uipanel(app.OuterGrid);
            app.MotionCompensationPanel.Title = 'Motion Compensation';
            app.MotionCompensationGrid = uigridlayout(app.MotionCompensationPanel);
            app.SpeedmsEditFieldLabel = uilabel(app.MotionCompensationGrid);
            app.SpeedmsEditFieldLabel.Text = 'Speed (m/s)';
            app.SpeedInput = uieditfield(app.MotionCompensationGrid, 'numeric');
            app.SpeedInput.ValueDisplayFormat = '%.2f';
            app.SpeedInput.Enable = 'off';
            app.TiltdegEditFieldLabel = uilabel(app.MotionCompensationGrid);
            app.TiltdegEditFieldLabel.Text = 'Tilt (deg)';
            app.TiltInput = uieditfield(app.MotionCompensationGrid, 'numeric');
            app.TiltInput.ValueDisplayFormat = '%.2f';
            app.TiltInput.Enable = 'off';
            app.SpeedDataButton = uibutton(app.MotionCompensationGrid, 'state');
            app.SpeedDataButton.Enable = 'off';
            app.SpeedDataButton.Text = 'Load Speed Data';
            app.SpeedDataPath = uieditfield(app.MotionCompensationGrid, 'text');
            app.SpeedDataPath.Editable = 'off';
            app.SpeedDataPath.Enable = 'off';
            app.ManualSpeedInputButton = uibutton(app.MotionCompensationGrid, 'state');
            app.ManualSpeedInputButton.Enable = 'off';
            app.ManualSpeedInputButton.Text = 'Manual Input';
            app.Lamp = uilamp(app.MotionCompensationGrid);
            app.MotionSynchronisationPanel = uipanel(app.OuterGrid);
            app.MotionSynchronisationPanel.Title = 'Motion Synchronisation';
            app.MotionSynchronisationGrid = uigridlayout(app.MotionSynchronisationPanel);
            app.BaselineLabel = uilabel(app.MotionSynchronisationGrid);
            app.BaselineLabel.Text = 'Baseline';
            app.TimeDelaysLabel = uilabel(app.MotionSynchronisationGrid);
            app.TimeDelaysLabel.Text = 'Time Delay (s)';
            app.MarkersCheckBox = uicheckbox(app.MotionSynchronisationGrid);
            app.MarkersCheckBox.ValueChangedFcn = createCallbackFcn(app, @markersChecked, true);
            app.MarkersCheckBox.Enable = 'off';
            app.MarkersCheckBox.Text = 'Markers';
            app.GRFCheckBox = uicheckbox(app.MotionSynchronisationGrid);
            app.GRFCheckBox.ValueChangedFcn = createCallbackFcn(app, @grfChecked, true);
            app.GRFCheckBox.Enable = 'off';
            app.GRFCheckBox.Text = 'GRF';
            app.EMGCheckBox = uicheckbox(app.MotionSynchronisationGrid);
            app.EMGCheckBox.ValueChangedFcn = createCallbackFcn(app, @emgChecked, true);
            app.EMGCheckBox.Enable = 'off';
            app.EMGCheckBox.Text = 'EMG';
            app.CalorimetryCheckBox = uicheckbox(app.MotionSynchronisationGrid);
            app.CalorimetryCheckBox.ValueChangedFcn = createCallbackFcn(app, @calorimetryChecked, true);
            app.CalorimetryCheckBox.Enable = 'off';
            app.CalorimetryCheckBox.Text = 'Calorimetry';
            app.MarkersDelay = uieditfield(app.MotionSynchronisationGrid, 'numeric');
            app.MarkersDelay.ValueDisplayFormat = '%.2f';
            app.MarkersDelay.ValueChangedFcn = createCallbackFcn(app, @markerDelayModified, true);
            app.MarkersDelay.Enable = 'off';
            app.GRFDelay = uieditfield(app.MotionSynchronisationGrid, 'numeric');
            app.GRFDelay.ValueDisplayFormat = '%.2f';
            app.GRFDelay.ValueChangedFcn = createCallbackFcn(app, @grfDelayModified, true);
            app.GRFDelay.Enable = 'off';
            app.EMGDelay = uieditfield(app.MotionSynchronisationGrid, 'numeric');
            app.EMGDelay.ValueDisplayFormat = '%.2f';
            app.EMGDelay.ValueChangedFcn = createCallbackFcn(app, @emgDelayModified, true);
            app.EMGDelay.Enable = 'off';
            app.CalorimetryDelay = uieditfield(app.MotionSynchronisationGrid, 'numeric');
            app.CalorimetryDelay.ValueDisplayFormat = '%.2f';
            app.CalorimetryDelay.ValueChangedFcn = createCallbackFcn(app, @calorimetryDelayModified, true);
            app.CalorimetryDelay.Enable = 'off';
            app.FooterToolbox = uilabel(app.OuterGrid);
            app.FooterToolbox.Text = 'Component of Gait Analysis Toolbox';
            app.FooterAuthor = uilabel(app.OuterGrid);
            app.FooterAuthor.Text = 'Daniel Gordon 2020';
            app.FeedbackPanel = uitextarea(app.OuterGrid);
            app.FeedbackPanel.Editable = 'off';
            app.ProcessButton = uibutton(app.OuterGrid, 'push');
            app.ProcessButton.Text = 'Process';
            app.DisplayErrorInfoSwitchLabel = uilabel(app.OuterGrid);
            app.DisplayErrorInfoSwitchLabel.Text = 'Display Error Info';
            app.ErrorSwitch = uiswitch(app.OuterGrid, 'slider');
            app.SaveDirectoryButton = uibutton(app.OuterGrid, 'push');
            app.SaveDirectoryButton.ButtonPushedFcn = createCallbackFcn(app, @getSaveDirectoryPath, true);
            app.SaveDirectoryButton.Text = 'Save Directory';
            app.SaveDirectoryPath = uieditfield(app.OuterGrid, 'text');
            app.SaveDirectoryPath.Editable = 'off';
            app.MarkerDataClearButton = uibutton(app.OuterGrid, 'push');
            app.MarkerDataClearButton.ButtonPushedFcn = createCallbackFcn(app, @clearMarkerDataPath, true);
            app.MarkerDataClearButton.Enable = 'off';
            app.MarkerDataClearButton.Text = 'Clear';
            app.GRFDataClearButton = uibutton(app.OuterGrid, 'push');
            app.GRFDataClearButton.ButtonPushedFcn = createCallbackFcn(app, @clearGRFDataPath, true);
            app.GRFDataClearButton.Enable = 'off';
            app.GRFDataClearButton.Text = 'Clear';
            app.EMGDataClearButton = uibutton(app.OuterGrid, 'push');
            app.EMGDataClearButton.ButtonPushedFcn = createCallbackFcn(app, @clearEMGDataPath, true);
            app.EMGDataClearButton.Enable = 'off';
            app.EMGDataClearButton.Text = 'Clear';
            app.CalorimetryDataClearButton = uibutton(app.OuterGrid, 'push');
            app.CalorimetryDataClearButton.ButtonPushedFcn = createCallbackFcn(app, @clearCalorimetryDataPath, true);
            app.CalorimetryDataClearButton.Enable = 'off';
            app.CalorimetryDataClearButton.Text = 'Clear';
            app.SaveDirectoryClearButton = uibutton(app.OuterGrid, 'push');
            app.SaveDirectoryClearButton.ButtonPushedFcn = createCallbackFcn(app, @clearSaveDirectoryPath, true);
            app.SaveDirectoryClearButton.Enable = 'off';
            app.SaveDirectoryClearButton.Text = 'Clear';
            % Show the figure after all components are created
            app.MotionUI.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MotionProcessor

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MotionUI)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MotionUI)
        end
    end
end