classdef ProcessMotionData < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MotionDataProcessingAppUIFigure  matlab.ui.Figure
        ChooseAnalysisModeDropDownLabel  matlab.ui.control.Label
        ChooseAnalysisModeDropDown     matlab.ui.control.DropDown
        MarkerDataFolderButton         matlab.ui.control.Button
        MarkerFolder                   matlab.ui.control.EditField
        GRFDataFolderButton            matlab.ui.control.Button
        GRFFolder                      matlab.ui.control.EditField
        SaveDirectoryButton            matlab.ui.control.Button
        SaveDirectory                  matlab.ui.control.EditField
        DisplayDetailedInfoCheckBox    matlab.ui.control.CheckBox
        ProcessButton                  matlab.ui.control.Button
        TextArea                       matlab.ui.control.TextArea
        ComponentofGaitAnalysisToolboxLabel  matlab.ui.control.Label
        DanielGordon2019Label          matlab.ui.control.Label
        DefaultSaveFoldersCheckBox     matlab.ui.control.CheckBox
        GRFEditFieldLabel              matlab.ui.control.Label
        GRFEditField                   matlab.ui.control.EditField
        MarkersEditFieldLabel          matlab.ui.control.Label
        MarkersEditField               matlab.ui.control.EditField
        SegmentationPanel              matlab.ui.container.Panel
        SegmentationModeButtonGroup_Custom  matlab.ui.container.ButtonGroup
        StanceButton_Custom            matlab.ui.control.RadioButton
        ToepeakButton_Custom           matlab.ui.control.RadioButton
        NoneButton                     matlab.ui.control.RadioButton
        CutoffLabel                    matlab.ui.control.Label
        StanceCutoffEditField_Custom   matlab.ui.control.NumericEditField
        FeetLabel_2                    matlab.ui.control.Label
        FeetDropDown_Custom            matlab.ui.control.DropDown
        MotionCompensationPanel        matlab.ui.container.Panel
        DirectionButtonGroup           matlab.ui.container.ButtonGroup
        NoneButton_2                   matlab.ui.control.RadioButton
        XButton                        matlab.ui.control.RadioButton
        YButton                        matlab.ui.control.RadioButton
        ZButton                        matlab.ui.control.RadioButton
        SpeedEditFieldLabel            matlab.ui.control.Label
        SpeedEditField                 matlab.ui.control.NumericEditField
        AxisRotationDataSynchronisationPanel  matlab.ui.container.Panel
        MarkersLabel                   matlab.ui.control.Label
        XEditFieldLabel_2              matlab.ui.control.Label
        Marker_X_Custom                matlab.ui.control.NumericEditField
        GRFLabel                       matlab.ui.control.Label
        GRF_X_Custom                   matlab.ui.control.NumericEditField
        YLabel                         matlab.ui.control.Label
        Marker_X_Custom_2              matlab.ui.control.NumericEditField
        ZLabel                         matlab.ui.control.Label
        Marker_X_Custom_3              matlab.ui.control.NumericEditField
        GRF_X_Custom_2                 matlab.ui.control.NumericEditField
        GRF_X_Custom_3                 matlab.ui.control.NumericEditField
        DataTimeDelayEditField_2Label_2  matlab.ui.control.Label
        DataTimeDelayEditField_Custom  matlab.ui.control.NumericEditField
    end


    methods (Access = private)
    
        function setGRFOptions(app, status)
            
            set(app.GRFDataFolderButton, 'Enable', status);
            set(app.GRF_X_UoE, 'Enable', status);
            set(app.GRF_Y_UoE, 'Enable', status);
            set(app.GRF_Z_UoE, 'Enable', status);
            set(app.GRF_X_Custom, 'Enable', status);
            set(app.GRF_Y_Custom, 'Enable', status);
            set(app.GRF_Z_Custom, 'Enable', status);
            
        end
    
        function setSegmentationOptions(app, status)

            setStanceOptions(app, status);
            setToeOptions(app, status);
            set(app.StanceCutoffEditField_UoE, 'Enable', status);
            set(app.StanceCutoffEditField_Custom, 'Enable', status);
            set(app.FeetDropDown_UoE, 'Enable', status);
            set(app.FeetDropDown_Custom, 'Enable', status);
            switch app.ChooseAnalysisModeDropDown.Value
                case 'Marker'
                    setStanceOptions(app, 'Off');
                    setToeOptions(app, status);
                case 'GRF'
                    setToeOptions(app, 'Off');
                    setStanceOptions(app, status);
                case 'Motion'
                    setToeOptions(app, status);
                    setStanceOptions(app, status);
            end
            
        end
    
        function setStanceOptions(app, status)
            
            set(app.StanceButton_UoE, 'Enable', status);
            set(app.StanceButton_Custom, 'Enable', status);
            set(app.StanceCutoffEditField_UoE, 'Enable', status);
            set(app.StanceCutoffEditField_Custom, 'Enable', status);
            
        end
    
        function setToeOptions(app, status)
            
            set(app.ToepeakButton_UoE, 'Enable', status);
            set(app.ToepeakButton_Custom, 'Enable', status);
        end
    
        function setMarkerOptions(app, status)
            
            set(app.MarkerDataFolderButton, 'Enable', status);
            set(app.Marker_X_UoE, 'Enable', status);
            set(app.Marker_Y_UoE, 'Enable', status);
            set(app.Marker_Z_UoE, 'Enable', status);
            set(app.Marker_X_Custom, 'Enable', status);
            set(app.Marker_Y_Custom, 'Enable', status);
            set(app.Marker_Z_Custom, 'Enable', status);
            
        end
    
        function setSynchronisationOptions(app, status)
           
            set(app.DataTimeDelayEditField_Custom, 'Enable', status);
            set(app.DataTimeDelayEditField_UoE, 'Enable', status);
            
        end
    
        function base = getBestStartPath(app)
            
            possibilities = {app.MarkerFolder.Value, app.GRFFolder.Value, app.SaveDirectory.Value};
            base = [];
            for i=1:length(possibilities)
                if ~isempty(possibilities{i})
                    components = strsplit(possibilities{i}, filesep);
                    base = strjoin(components(1:end-1), filesep);
                    break;
                end
            end
            
        end
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            disableOptions(app)
            setSegmentationOptions(app, 'off')
        end

        % Button pushed function: MarkerDataFolderButton
        function getMarkerFolder(app, event)
            base = getBestStartPath(app);
            try
                app.MarkerFolder.Value = uigetdir(base, 'Select folder containing marker data');
            catch 
            end
            app.MotionDataProcessingAppUIFigure.Visible = 'off';
            app.MotionDataProcessingAppUIFigure.Visible = 'on';
        end

        % Value changed function: ChooseAnalysisModeDropDown
        function disableOptions(app, event)
            value = app.ChooseAnalysisModeDropDown.Value;
            switch value
                case 'Static'
                    set(app.MarkersEditFieldLabel, 'Text', 'Static');
                    set(app.GRFEditField, 'Enable', 'off');
                    set(app.MarkersEditField, 'Enable', 'on');
                    set(app.SegmentationSwitch, 'Enable', 'off');
                    setGRFOptions(app, 'off');
                    setMarkerOptions(app, 'on');
                    setSynchronisationOptions(app, 'off');
                case 'Marker'
                    set(app.MarkersEditFieldLabel, 'Text', 'Markers');
                    set(app.GRFEditField, 'Enable', 'off');
                    set(app.MarkersEditField, 'Enable', 'on');
                    app.StanceButton_Custom.Value = 0;
                    app.StanceButton_UoE.Value = 0;
                    set(app.SegmentationSwitch, 'Enable', 'on');
                    setGRFOptions(app, 'off');
                    setMarkerOptions(app, 'on');
                    setSynchronisationOptions(app, 'off');
                    setSegmentationOptions(app, app.SegmentationSwitch.Value);
                case 'GRF'
                    set(app.MarkersEditFieldLabel, 'Text', 'Markers');
                    set(app.GRFEditField, 'Enable', 'on');
                    set(app.MarkersEditField, 'Enable', 'off');
                    app.StanceButton_Custom.Value = 1;
                    app.StanceButton_UoE.Value = 1;
                    set(app.SegmentationSwitch, 'Enable', 'on');
                    setGRFOptions(app, 'on');
                    setMarkerOptions(app, 'off');
                    setSynchronisationOptions(app, 'off');
                    setSegmentationOptions(app, app.SegmentationSwitch.Value);
                case 'Motion'
                    set(app.MarkersEditFieldLabel, 'Text', 'Markers');
                    set(app.GRFEditField, 'Enable', 'on');
                    set(app.MarkersEditField, 'Enable', 'on');
                    set(app.SegmentationSwitch, 'Enable', 'on');
                    setGRFOptions(app, 'on');
                    setMarkerOptions(app, 'on');
                    setSynchronisationOptions(app, 'on');
                    setSegmentationOptions(app, app.SegmentationSwitch.Value);
            end
        end

        % Callback function
        function allowSegmentation(app, event)
            setSegmentationOptions(app, app.SegmentationSwitch.Value)
        end

        % Button pushed function: GRFDataFolderButton
        function getGRFData(app, event)
            base = getBestStartPath(app);
            try
                app.GRFFolder.Value = uigetdir(base, 'Select folder containing GRF data');
            catch
            end
            app.MotionDataProcessingAppUIFigure.Visible = 'off';
            app.MotionDataProcessingAppUIFigure.Visible = 'on';
        end

        % Button pushed function: SaveDirectoryButton
        function getOrCreateResultsDir(app, event)
            base = getBestStartPath(app);
            try
                app.SaveDirectory.Value = uigetdir(base, 'Select or create results folder');
            catch
            end
            app.MotionDataProcessingAppUIFigure.Visible = 'off';
            app.MotionDataProcessingAppUIFigure.Visible = 'on';
        end

        % Button pushed function: ProcessButton
        function processData(app, event)
            if isempty(app.SaveDirectory.Value)
                app.TextArea.Value = 'Processing requires save directory.';
            end
            
            % Default folder names.
            if app.DefaultSaveFoldersCheckBox.Value
                settings.static_folder = 'Static';
                settings.marker_folder = 'Markers';
                settings.grf_folder = 'GRF';
            else
                settings.static_folder = app.MarkersEditField.Value;
                settings.marker_folder = app.MarkersEditField.Value;
                settings.grf_folder = app.GRFEditField.Value;
            end
            
            settings.analysis = app.ChooseAnalysisModeDropDown.Value;
            settings.save_dir = app.SaveDirectory.Value;
            settings.info = app.DisplayDetailedInfoCheckBox.Value;
            type = app.TabGroup.SelectedTab.Title;
            switch app.ChooseAnalysisModeDropDown.Value
                case 'Static'
                    if isempty(app.MarkerFolder.Value)
                        app.TextArea.Value = 'Static processing requires marker data folder.';
                        return
                    else
                        settings.markers = app.MarkerFolder.Value;
                        settings.marker_rotations{1} = app.(['Marker_X_' type]).Value;
                        settings.marker_rotations{2} = app.(['Marker_Y_' type]).Value;
                        settings.marker_rotations{3} = app.(['Marker_Z_' type]).Value;
                    end
                case 'Marker'
                    if isempty(app.MarkerFolder.Value)
                        app.TextArea.Value = 'Marker processing requires marker data folder.';
                        return
                    else
                        settings.markers = app.MarkerFolder.Value;
                        settings.marker_rotations{1} = app.(['Marker_X_' type]).Value;
                        settings.marker_rotations{2} = app.(['Marker_Y_' type]).Value;
                        settings.marker_rotations{3} = app.(['Marker_Z_' type]).Value;
                    end
                case 'GRF'
                    if isempty(app.GRFFolder.Value)
                        app.TextArea.Value = 'GRF processing requires GRF data folder.';
                        return
                    else
                        settings.grfs = app.GRFFolder.Value;
                        settings.grf_rotations{1} = app.(['GRF_X_' type]).Value;
                        settings.grf_rotations{2} = app.(['GRF_Y_' type]).Value;
                        settings.grf_rotations{3} = app.(['GRF_Z_' type]).Value;
                    end
                case 'Motion'
                    if isempty(app.GRFFolder.Value) || isempty(app.MarkerFolder.Value)
                        app.TextArea.Value = 'Motion processing requires marker + GRF data folders.';
                        return
                    else
                        settings.markers = app.MarkerFolder.Value;
                        settings.marker_rotations{1} = app.(['Marker_X_' type]).Value;
                        settings.marker_rotations{2} = app.(['Marker_Y_' type]).Value;
                        settings.marker_rotations{3} = app.(['Marker_Z_' type]).Value;
                        settings.grfs = app.GRFFolder.Value;
                        settings.grf_rotations{1} = app.(['GRF_X_' type]).Value;
                        settings.grf_rotations{2} = app.(['GRF_Y_' type]).Value;
                        settings.grf_rotations{3} = app.(['GRF_Z_' type]).Value;
                        settings.time_delay = app.(['DataTimeDelayEditField_' type]).Value;
                    end
            end
            app.TextArea.Value = 'Processing...';
            drawnow;
            if strcmp(app.SegmentationSwitch.Value, 'On') && ~strcmp(app.ChooseAnalysisModeDropDown.Value, 'Static')
                if app.(['StanceButton_' type]).Value
                    settings.mode = 'stance';
                else
                    settings.mode = 'toe-peak';
                end
                settings.cutoff = app.(['StanceCutoffEditField_' type]).Value;
                if strcmp(app.(['FeetDropDown_' type]).Value, 'Left')
                    settings.feet = {'left'};
                elseif strcmp(app.(['FeetDropDown_' type]).Value, 'Right')
                    settings.feet = {'right'};
                else
                    settings.feet = {'left', 'right'};
                end
            end
            
            err = batchProcessData(settings);
            if ~err
                app.TextArea.Value = 'Processing complete.';
            else
                app.TextArea.Value = 'Processing finished with errors - see MATLAB command window for more details.';
            end
        end

        % Selection changed function: 
        % SegmentationModeButtonGroup_Custom
        function disableCutoffCustom(app, event)
            if app.ToepeakButton_Custom.Value
                set(app.StanceCutoffEditField_Custom, 'enable', 'off');
            else
                set(app.StanceCutoffEditField_Custom, 'enable', 'on');
            end
            
        end

        % Callback function
        function disableCutoffUoE(app, event)
            if app.ToepeakButton_UoE.Value
                set(app.StanceCutoffEditField_UoE, 'enable', 'off');
            else
                set(app.StanceCutoffEditField_UoE, 'enable', 'on');
            end
            
        end

        % Callback function
        function datasetOptions2(app, event)
            value = app.StructureDataCheckBox.Value;
            if value
                set(app.DatasetDescriptorButton, 'Enable', 'On');
            else
                set(app.DatasetDescriptorButton, 'Enable', 'Off');
            end
        end

        % Value changed function: DefaultSaveFoldersCheckBox
        function allowSaveChanges(app, event)
            value = app.DefaultSaveFoldersCheckBox.Value;
            if ~value
                set(app.GRFEditField, 'Visible', 'on');
                set(app.MarkersEditField, 'Visible', 'on');
                set(app.GRFEditFieldLabel, 'Visible', 'on');
                set(app.MarkersEditFieldLabel, 'Visible', 'on');
            else
                set(app.GRFEditField, 'Visible', 'off');
                set(app.MarkersEditField, 'Visible', 'off');
                set(app.GRFEditFieldLabel, 'Visible', 'off');
                set(app.MarkersEditFieldLabel, 'Visible', 'off');
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MotionDataProcessingAppUIFigure = uifigure;
            app.MotionDataProcessingAppUIFigure.Name = 'Motion Data Processing App';
            app.ChooseAnalysisModeDropDownLabel = uilabel(app.MotionDataProcessingAppUIFigure);
            app.ChooseAnalysisModeDropDownLabel.Text = 'Choose Analysis Mode';
            app.ChooseAnalysisModeDropDown = uidropdown(app.MotionDataProcessingAppUIFigure);
            app.ChooseAnalysisModeDropDown.Items = {'Static', 'Marker', 'GRF', 'Motion'};
            app.ChooseAnalysisModeDropDown.ValueChangedFcn = createCallbackFcn(app, @disableOptions, true);
            app.ChooseAnalysisModeDropDown.Value = 'Static';
            app.MarkerDataFolderButton = uibutton(app.MotionDataProcessingAppUIFigure, 'push');
            app.MarkerDataFolderButton.ButtonPushedFcn = createCallbackFcn(app, @getMarkerFolder, true);
            app.MarkerDataFolderButton.Text = 'Marker Data Folder';
            app.MarkerFolder = uieditfield(app.MotionDataProcessingAppUIFigure, 'text');
            app.MarkerFolder.Editable = 'off';
            app.GRFDataFolderButton = uibutton(app.MotionDataProcessingAppUIFigure, 'push');
            app.GRFDataFolderButton.ButtonPushedFcn = createCallbackFcn(app, @getGRFData, true);
            app.GRFDataFolderButton.Text = 'GRF Data Folder';
            app.GRFFolder = uieditfield(app.MotionDataProcessingAppUIFigure, 'text');
            app.GRFFolder.Editable = 'off';
            app.SaveDirectoryButton = uibutton(app.MotionDataProcessingAppUIFigure, 'push');
            app.SaveDirectoryButton.ButtonPushedFcn = createCallbackFcn(app, @getOrCreateResultsDir, true);
            app.SaveDirectoryButton.Text = 'Save Directory';
            app.SaveDirectory = uieditfield(app.MotionDataProcessingAppUIFigure, 'text');
            app.SaveDirectory.Editable = 'off';
            app.DisplayDetailedInfoCheckBox = uicheckbox(app.MotionDataProcessingAppUIFigure);
            app.DisplayDetailedInfoCheckBox.Text = 'Display Detailed Info';
            app.ProcessButton = uibutton(app.MotionDataProcessingAppUIFigure, 'push');
            app.ProcessButton.ButtonPushedFcn = createCallbackFcn(app, @processData, true);
            app.ProcessButton.Text = 'Process';
            app.TextArea = uitextarea(app.MotionDataProcessingAppUIFigure);
            app.ComponentofGaitAnalysisToolboxLabel = uilabel(app.MotionDataProcessingAppUIFigure);
            app.ComponentofGaitAnalysisToolboxLabel.Text = 'Component of Gait Analysis Toolbox  ';
            app.DanielGordon2019Label = uilabel(app.MotionDataProcessingAppUIFigure);
            app.DanielGordon2019Label.Text = 'Daniel Gordon 2019';
            app.DefaultSaveFoldersCheckBox = uicheckbox(app.MotionDataProcessingAppUIFigure);
            app.DefaultSaveFoldersCheckBox.ValueChangedFcn = createCallbackFcn(app, @allowSaveChanges, true);
            app.DefaultSaveFoldersCheckBox.Text = 'Default Save Folders';
            app.DefaultSaveFoldersCheckBox.Value = true;
            app.GRFEditFieldLabel = uilabel(app.MotionDataProcessingAppUIFigure);
            app.GRFEditFieldLabel.Visible = 'off';
            app.GRFEditFieldLabel.Text = 'GRF';
            app.GRFEditField = uieditfield(app.MotionDataProcessingAppUIFigure, 'text');
            app.GRFEditField.Visible = 'off';
            app.GRFEditField.Value = 'GRF';
            app.MarkersEditFieldLabel = uilabel(app.MotionDataProcessingAppUIFigure);
            app.MarkersEditFieldLabel.Visible = 'off';
            app.MarkersEditFieldLabel.Text = 'Markers';
            app.MarkersEditField = uieditfield(app.MotionDataProcessingAppUIFigure, 'text');
            app.MarkersEditField.Visible = 'off';
            app.MarkersEditField.Value = 'Markers';
            app.SegmentationPanel = uipanel(app.MotionDataProcessingAppUIFigure);
            app.SegmentationPanel.Title = 'Segmentation';
            app.SegmentationModeButtonGroup_Custom = uibuttongroup(app.SegmentationPanel);
            app.SegmentationModeButtonGroup_Custom.SelectionChangedFcn = createCallbackFcn(app, @disableCutoffCustom, true);
            app.SegmentationModeButtonGroup_Custom.Title = 'Mode';
            app.StanceButton_Custom = uiradiobutton(app.SegmentationModeButtonGroup_Custom);
            app.StanceButton_Custom.Text = 'Stance';
            app.ToepeakButton_Custom = uiradiobutton(app.SegmentationModeButtonGroup_Custom);
            app.ToepeakButton_Custom.Text = 'Toe-peak';
            app.NoneButton = uiradiobutton(app.SegmentationModeButtonGroup_Custom);
            app.NoneButton.Text = 'None';
            app.CutoffLabel = uilabel(app.SegmentationPanel);
            app.CutoffLabel.Text = 'Cutoff';
            app.StanceCutoffEditField_Custom = uieditfield(app.SegmentationPanel, 'numeric');
            app.StanceCutoffEditField_Custom.Value = 2;
            app.FeetLabel_2 = uilabel(app.SegmentationPanel);
            app.FeetLabel_2.Text = 'Feet';
            app.FeetDropDown_Custom = uidropdown(app.SegmentationPanel);
            app.FeetDropDown_Custom.Items = {'Left', 'Right', 'Both'};
            app.FeetDropDown_Custom.Value = 'Right';
            app.MotionCompensationPanel = uipanel(app.MotionDataProcessingAppUIFigure);
            app.MotionCompensationPanel.Title = 'Motion Compensation';
            app.DirectionButtonGroup = uibuttongroup(app.MotionCompensationPanel);
            app.DirectionButtonGroup.Title = 'Direction';
            app.NoneButton_2 = uiradiobutton(app.DirectionButtonGroup);
            app.NoneButton_2.Text = 'None';
            app.NoneButton_2.Value = true;
            app.XButton = uiradiobutton(app.DirectionButtonGroup);
            app.XButton.Text = 'X';
            app.YButton = uiradiobutton(app.DirectionButtonGroup);
            app.YButton.Text = 'Y';
            app.ZButton = uiradiobutton(app.DirectionButtonGroup);
            app.ZButton.Text = 'Z';
            app.SpeedEditFieldLabel = uilabel(app.MotionCompensationPanel);
            app.SpeedEditFieldLabel.Text = 'Speed';
            app.SpeedEditField = uieditfield(app.MotionCompensationPanel, 'numeric');
            app.AxisRotationDataSynchronisationPanel = uipanel(app.MotionDataProcessingAppUIFigure);
            app.AxisRotationDataSynchronisationPanel.Title = 'Axis Rotation & Data Synchronisation';
            app.MarkersLabel = uilabel(app.AxisRotationDataSynchronisationPanel);
            app.MarkersLabel.Text = 'Markers';
            app.XEditFieldLabel_2 = uilabel(app.AxisRotationDataSynchronisationPanel);
            app.XEditFieldLabel_2.Text = 'X';
            app.Marker_X_Custom = uieditfield(app.AxisRotationDataSynchronisationPanel, 'numeric');
            app.GRFLabel = uilabel(app.AxisRotationDataSynchronisationPanel);
            app.GRFLabel.Text = 'GRF';
            app.GRF_X_Custom = uieditfield(app.AxisRotationDataSynchronisationPanel, 'numeric');
            app.YLabel = uilabel(app.AxisRotationDataSynchronisationPanel);
            app.YLabel.Text = 'Y';
            app.Marker_X_Custom_2 = uieditfield(app.AxisRotationDataSynchronisationPanel, 'numeric');
            app.Marker_X_Custom_2.Value = 270;
            app.ZLabel = uilabel(app.AxisRotationDataSynchronisationPanel);
            app.ZLabel.Text = 'Z';
            app.Marker_X_Custom_3 = uieditfield(app.AxisRotationDataSynchronisationPanel, 'numeric');
            app.GRF_X_Custom_2 = uieditfield(app.AxisRotationDataSynchronisationPanel, 'numeric');
            app.GRF_X_Custom_2.Value = 90;
            app.GRF_X_Custom_3 = uieditfield(app.AxisRotationDataSynchronisationPanel, 'numeric');
            app.DataTimeDelayEditField_2Label_2 = uilabel(app.AxisRotationDataSynchronisationPanel);
            app.DataTimeDelayEditField_2Label_2.Text = 'Data Time Delay';
            app.DataTimeDelayEditField_Custom = uieditfield(app.AxisRotationDataSynchronisationPanel, 'numeric');
            app.DataTimeDelayEditField_Custom.Value = 0.02667;
        end
    end

    methods (Access = public)

        % Construct app
        function app = ProcessMotionData

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MotionDataProcessingAppUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MotionDataProcessingAppUIFigure)
        end
    end
end